classdef histogram_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ZapiszhistogramwypadkowyButton  matlab.ui.control.Button
        ZapiszhistogramYButton          matlab.ui.control.Button
        ZapiszhistogramXButton          matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp %Main App
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, t_hist, hist_X, hist_Y, hist_Wypadkowy)
            %store main window object
            app.CallingApp=caller;
            %add histograms to axes
            h1=histogram(app.UIAxes, hist_X, t_hist);
            h2=histogram(app.UIAxes2, hist_Y, t_hist);
            hist_Wypadkowy=h1.Values()+h2.Values();
            disp_t_hist=t_hist(2:end);
            bar(app.UIAxes3,disp_t_hist,hist_Wypadkowy,'histc');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.WywietlhistogramyTCIButton.Enable = "on";
            delete(app)
        end

        % Button pushed function: ZapiszhistogramXButton
        function ZapiszhistogramXButtonPushed(app, event)
            % Create a temporary figure with axes.
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);
            % Copy all UIAxes children, take over axes limits and aspect ratio.            
            allChildren = app.UIAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.UIAxes.XLim;
            figAxes.YLim = app.UIAxes.YLim;
            figAxes.ZLim = app.UIAxes.ZLim;
            figAxes.Title.String='Histogram TCI kierunek X';
            figAxes.DataAspectRatio = app.UIAxes.DataAspectRatio;
            % Save as png and fig files.
            [file,path] = uiputfile('*.png');
            %save(fullfile(path,file),"fig")
            if file==0
              return
            end
             saveas(fig, fullfile(path,file));
            % Delete the temporary figure.
            delete(fig);
        end

        % Button pushed function: ZapiszhistogramYButton
        function ZapiszhistogramYButtonPushed(app, event)
            % Create a temporary figure with axes.
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);
            % Copy all UIAxes children, take over axes limits and aspect ratio.            
            allChildren = app.UIAxes2.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.UIAxes2.XLim;
            figAxes.YLim = app.UIAxes2.YLim;
            figAxes.ZLim = app.UIAxes2.ZLim;
            figAxes.Title.String='Histogram TCI kierunek Y';
            figAxes.DataAspectRatio = app.UIAxes2.DataAspectRatio;
            % Save as png and fig files.
            [file,path] = uiputfile('*.png');
            %save(fullfile(path,file),"fig")
            if file==0
              return
            end
             saveas(fig, fullfile(path,file));
            % Delete the temporary figure.
            delete(fig);
        end

        % Button pushed function: ZapiszhistogramwypadkowyButton
        function ZapiszhistogramwypadkowyButtonPushed(app, event)
            % Create a temporary figure with axes.
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);
            % Copy all UIAxes children, take over axes limits and aspect ratio.            
            allChildren = app.UIAxes3.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.UIAxes3.XLim;
            figAxes.YLim = app.UIAxes3.YLim;
            figAxes.ZLim = app.UIAxes3.ZLim;
            figAxes.Title.String='Histogram TCI wypadkowy';
            figAxes.DataAspectRatio = app.UIAxes3.DataAspectRatio;
            % Save as png and fig files.
            [file,path] = uiputfile('*.png');
            %save(fullfile(path,file),"fig")
            if file==0
              return
            end
             saveas(fig, fullfile(path,file));
            % Delete the temporary figure.
            delete(fig);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Histagram TCI kierunek X')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Histogram TCI kierunek Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Histogram TCI wypadkowy')
            app.ZapiszhistogramXButton = uibutton(app.UIFigure, 'push');
            app.ZapiszhistogramXButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszhistogramXButtonPushed, true);
            app.ZapiszhistogramXButton.Text = 'Zapisz histogram X';
            app.ZapiszhistogramYButton = uibutton(app.UIFigure, 'push');
            app.ZapiszhistogramYButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszhistogramYButtonPushed, true);
            app.ZapiszhistogramYButton.Text = 'Zapisz histogram Y';
            app.ZapiszhistogramwypadkowyButton = uibutton(app.UIFigure, 'push');
            app.ZapiszhistogramwypadkowyButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszhistogramwypadkowyButtonPushed, true);
            app.ZapiszhistogramwypadkowyButton.Text = 'Zapisz histogram wypadkowy';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = histogram_window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end